classdef dsp_part1_new < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        PLOTSTFTButton                  matlab.ui.control.Button
        PLOTDATAButton                  matlab.ui.control.Button
        TabGroup2                       matlab.ui.container.TabGroup
        AcquisitionTab                  matlab.ui.container.Tab
        LiveLamp                        matlab.ui.control.Lamp
        LiveLampLabel                   matlab.ui.control.Label
        HzLabel                         matlab.ui.control.Label
        sLabel                          matlab.ui.control.Label
        RecordFrequencyEditField        matlab.ui.control.EditField
        RecordFrequencyEditFieldLabel   matlab.ui.control.Label
        RecordDurationEditField         matlab.ui.control.EditField
        RecordDurationEditFieldLabel    matlab.ui.control.Label
        StartRecordingButton            matlab.ui.control.Button
        ORLabel                         matlab.ui.control.Label
        LoadDataButton                  matlab.ui.control.Button
        GenerationTab                   matlab.ui.container.Tab
        SinusoidalSignalPanel           matlab.ui.container.Panel
        ConcatanateButton               matlab.ui.control.Button
        GenerateButton                  matlab.ui.control.Button
        PhaseEditField                  matlab.ui.control.NumericEditField
        PhaseEditFieldLabel             matlab.ui.control.Label
        FrequencyEditField              matlab.ui.control.NumericEditField
        FrequencyEditFieldLabel         matlab.ui.control.Label
        AmplitudeEditField              matlab.ui.control.NumericEditField
        AmplitudeEditFieldLabel         matlab.ui.control.Label
        WindowedSinusoidalPanel         matlab.ui.container.Panel
        ConcatanateButton_3             matlab.ui.control.Button
        LengthEditField                 matlab.ui.control.NumericEditField
        LengthEditFieldLabel            matlab.ui.control.Label
        StartingTimeEditField           matlab.ui.control.NumericEditField
        StartingTimeEditFieldLabel      matlab.ui.control.Label
        GenerateButton_2                matlab.ui.control.Button
        PhaseEditField_2                matlab.ui.control.NumericEditField
        PhaseEditField_2Label           matlab.ui.control.Label
        FrequencyEditField_2            matlab.ui.control.NumericEditField
        FrequencyEditField_2Label       matlab.ui.control.Label
        AmplitudeEditField_2            matlab.ui.control.NumericEditField
        AmplitudeEditField_2Label       matlab.ui.control.Label
        WindowTypeDropDown              matlab.ui.control.DropDown
        WindowTypeDropDownLabel         matlab.ui.control.Label
        DurationEditField               matlab.ui.control.NumericEditField
        DurationEditFieldLabel          matlab.ui.control.Label
        SamplingFrequencyEditField      matlab.ui.control.NumericEditField
        SamplingFrequencyEditFieldLabel  matlab.ui.control.Label
        MultipleComponentsPanel         matlab.ui.container.Panel
        ConcatanateButton_2             matlab.ui.control.Button
        FrequencyofComponentsEditField  matlab.ui.control.EditField
        FrequencyofComponentsEditFieldLabel  matlab.ui.control.Label
        PleaseseperatethecomponentswithcommaLabel  matlab.ui.control.Label
        PhaseofComponentsEditField      matlab.ui.control.EditField
        PhaseofComponentsEditFieldLabel  matlab.ui.control.Label
        AmplitudeofComponentsEditField  matlab.ui.control.EditField
        AmplitudeofComponentsEditFieldLabel  matlab.ui.control.Label
        GenerateButton_3                matlab.ui.control.Button
        NumberofComponentsEditField     matlab.ui.control.NumericEditField
        NumberofComponentsEditFieldLabel  matlab.ui.control.Label
        DataTypeDropDown                matlab.ui.control.DropDown
        DataTypeDropDownLabel           matlab.ui.control.Label
        SpectrogramTab                  matlab.ui.container.Tab
        WindowFunctionDropDown          matlab.ui.control.DropDown
        WindowFunctionDropDownLabel     matlab.ui.control.Label
        WindowShiftEditField            matlab.ui.control.NumericEditField
        WindowShiftEditFieldLabel       matlab.ui.control.Label
        WindowLengthEditField           matlab.ui.control.NumericEditField
        WindowLengthEditFieldLabel      matlab.ui.control.Label
        DataSelectionLabel              matlab.ui.control.Label
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Data;
        Frequency;
        Windowfunctiontype;
        Windowfunctiontype2;
        Windowfunction;
    end
    
    methods (Access = private)
        

        function [total_stft,M] = STFT(app,sequence,window_sequence,window_shift)
            % the function takes the sequence, the window sequence, and the window
            % shift as input.

            % working with row vectors.
            disp(1);
            [a,b] = size(sequence);
            if b<a
                sequence = sequence';
            end
            
            % the length of the window sequence is 2*M+1, so we can use this to
            % calculate M.
            M = (length(window_sequence)-1)/2;
            window_length = 2*M+1;
            
            % values at which we will calculate the STFT
            n0_values = M+1:window_shift:length(sequence)-M;
            % should start from M+1 since we need M previous values while calculating 
            % the STFT. (+1 because of matlab indexing)
            
            % each column are STFT results at a certain n0.
            total_stft = zeros(window_length,length(n0_values));
            for i=1:length(n0_values)
                % get the part of the sequence we will use to calculate STFT.
                current_sequence = sequence(n0_values(i)-M:n0_values(i)+M);
                % apply windowing operation.
                windowed_sequence = current_sequence .*window_sequence;
            
                % calculate the magnitude of the fft in dB scale.
%                 total_stft(:,i) = 20*log10(abs(fft(windowed_sequence)));
                fft_result = fft(windowed_sequence);
                power_spectral_density = abs(fft_result).^2;
                freq_resolution = app.Frequency / (2*M+1);
                power_spectral_density = power_spectral_density / freq_resolution;
        
                total_stft(:,i) = 10*log10(power_spectral_density);
            end
        end


        function plotting_data(app)
            
            length_in_s = length(app.Data)/app.Frequency;
            t = linspace(0, length_in_s,length(app.Data));
            plot(app.UIAxes,t,app.Data);


            %length_in_s = length(app.Data)/app.Frequency;
            %xlim(app.UIAxes,[0, length_in_s]);
            
         
        end
        function generate_window_function(app)
            if app.Windowfunctiontype == "Rectangular"
                app.Windowfunction = ones(1,app.WindowLengthEditField.Value);
            elseif app.Windowfunctiontype  == "Hanning"
                app.Windowfunction = hann(app.WindowLengthEditField.Value)';
            elseif app.Windowfunctiontype  == "Hamming"
                app.Windowfunction = hamming(app.WindowLengthEditField.Value)';
            elseif app.Windowfunctiontype  == "Blackman"
                app.Windowfunction = blackman(app.WindowLengthEditField.Value)';
            end
        end
        function plotting_stft(app)
            generate_window_function(app);
            [total_stft,M] = STFT(app,app.Data,app.Windowfunction,app.WindowShiftEditField.Value);
            
            length_in_s = length(app.Data)/app.Frequency;
            t = linspace(0, length_in_s, length(app.Data));
            data = total_stft(1:M+1, :);  % Considering up to Nyquist frequency
        
            % Correct frequency axis scaling
            freq_resolution = app.Frequency / (2*M+1);
            freq = 0:freq_resolution:app.Frequency/2;
        
            imagesc(app.UIAxes_2, t, freq, data);
            xlim(app.UIAxes_2, [0, length(t)/app.Frequency]);
            cbar = colorbar(app.UIAxes_2);
            cbar.Label.String = "Power / frequency (dB / Hz)";

            set(app.UIAxes_2, 'YDir', 'normal');
            ylim(app.UIAxes_2, [0, freq(end)]);
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.LiveLamp.Color = [1.0, 0, 0];
            app.SinusoidalSignalPanel.Visible = 'on';
            app.WindowedSinusoidalPanel.Visible = 'off';
            app.MultipleComponentsPanel.Visible = 'off';
            app.Windowfunctiontype = "Rectangular";
            app.Windowfunctiontype2 = "Rectangular";

        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            [AttTrans,PathName] = uigetfile({'*.wav';'*.mp3'},'Select audiofile');
            [x,Fs]= audioread([PathName , AttTrans]);
            app.Data = x;
            app.Frequency = Fs;
            
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
           
        end

        % Button pushed function: PLOTDATAButton
        function PLOTDATAButtonPushed(app, event)
            plotting_data(app);
        end

        % Button pushed function: StartRecordingButton
        function StartRecordingButtonPushed(app, event)
            duration = str2double(app.RecordDurationEditField.Value);
            freq = str2double(app.RecordFrequencyEditField.Value);
            
            recObj = audiorecorder(freq,16,1);
            
            app.LiveLamp.Color = [0, 1.0, 0];
            recordblocking(recObj,duration);
            app.LiveLamp.Color = [1.0, 0, 0];
            
            data = getaudiodata(recObj);
            app.Data = data;
            app.Frequency = freq;
        end

        % Value changed function: RecordDurationEditField
        function RecordDurationEditFieldValueChanged(app, event)
      
        end

        % Value changed function: RecordFrequencyEditField
        function RecordFrequencyEditFieldValueChanged(app, event)

        end

        % Button pushed function: PLOTSTFTButton
        function PLOTSTFTButtonPushed(app, event)
            plotting_stft(app);
        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            Amplitude = app.AmplitudeEditField.Value;
            Freq = app.FrequencyEditField.Value;
            Phase = app.PhaseEditField.Value;
            
            n = app.SamplingFrequencyEditField.Value*app.DurationEditField.Value;
            duration = app.DurationEditField.Value;
            t = linspace(0,duration,n);
            
            app.Data = Amplitude*cos(2*pi*Freq*t+Phase);
            app.Frequency = app.SamplingFrequencyEditField.Value;
        end

        % Value changed function: DataTypeDropDown
        function DataTypeDropDownValueChanged(app, event)
            value = app.DataTypeDropDown.Value;
            
            if(value == "Sinusoidal Signal")
                
                app.MultipleComponentsPanel.Visible = 'off';
                app.WindowedSinusoidalPanel.Visible = 'off';
                app.SinusoidalSignalPanel.Visible = 'on';
                
            end
            
            if(value == "Windowed Sinusoidal")
                app.SinusoidalSignalPanel.Visible = 'off';
                app.MultipleComponentsPanel.Visible = 'off';
                app.WindowedSinusoidalPanel.Visible = 'on';
            end
            
            if(value == "Multiple Components")
                app.SinusoidalSignalPanel.Visible = 'off';
                app.WindowedSinusoidalPanel.Visible = 'off';
                app.MultipleComponentsPanel.Visible = 'on';
                
            end
        end

        % Button pushed function: GenerateButton_2
        function GenerateButton_2Pushed(app, event)
            Amp = app.AmplitudeEditField_2.Value;
            Freq = app.FrequencyEditField_2.Value;
            Pha = app.PhaseEditField_2.Value;
            t0 = app.StartingTimeEditField.Value;
            samp_freq = app.SamplingFrequencyEditField.Value;
            delta = app.LengthEditField.Value;
            
            n = app.SamplingFrequencyEditField.Value*app.DurationEditField.Value;
            duration = app.DurationEditField.Value;
            t = linspace(0,duration,n);
            s = Amp*cos(2*pi*Freq*t+Pha);
            
            if app.Windowfunctiontype2  == "Rectangular"
               window = zeros(1,n);
               window((t0*samp_freq):(t0*samp_freq)+(delta*samp_freq)) = 1;
               app.Data = window.*s; 

            elseif app.Windowfunctiontype2  == "Hanning"
               window = zeros(1,n);
               window((t0*samp_freq):(t0*samp_freq)+(delta*samp_freq)) = hann(delta*samp_freq+1)';
               app.Data = window.*s; 
            elseif app.Windowfunctiontype2  == "Hamming"
               window = zeros(1,n);
               window((t0*samp_freq):(t0*samp_freq)+(delta*samp_freq)) = hamming(delta*samp_freq+1)';
               app.Data = window.*s; 
            elseif app.Windowfunctiontype2  == "Blackman"
               window = zeros(1,n);
               window((t0*samp_freq):(t0*samp_freq)+(delta*samp_freq)) = blackman(delta*samp_freq+1)';
               app.Data = window.*s; 
            end
             app.Frequency = app.SamplingFrequencyEditField.Value;
     
            
        end

        % Button pushed function: GenerateButton_3
        function GenerateButton_3Pushed(app, event)
            Amp = str2num(app.AmplitudeofComponentsEditField.Value);
            Pha = str2num(app.PhaseofComponentsEditField.Value);
            Freq = str2num(app.FrequencyofComponentsEditField.Value);
            
            N = app.NumberofComponentsEditField.Value;
            
            n = app.SamplingFrequencyEditField.Value*app.DurationEditField.Value;
            duration = app.DurationEditField.Value;
            t = linspace(0,duration,n);
            
            summ = zeros(1,n);
            sines = zeros(N,n);
            for ind = 1:N
                sines(ind,:) = Amp(ind)*cos(2*pi*Freq(ind)*t+Pha(ind));
            end
            summ = sum(sines,1);
            
            app.Data = summ;
            app.Frequency = app.SamplingFrequencyEditField.Value; 
         
        end

        % Value changed function: WindowFunctionDropDown
        function WindowFunctionDropDownValueChanged(app, event)
            app.Windowfunctiontype = app.WindowFunctionDropDown.Value;

        end

        % Value changed function: WindowTypeDropDown
        function WindowTypeDropDownValueChanged(app, event)
            app.Windowfunctiontype2 = app.WindowTypeDropDown.Value;
            
        end

        % Button pushed function: ConcatanateButton
        function ConcatanateButtonPushed(app, event)
            Amplitude = app.AmplitudeEditField.Value;
            Freq = app.FrequencyEditField.Value;
            Phase = app.PhaseEditField.Value;
            
            n = app.SamplingFrequencyEditField.Value*app.DurationEditField.Value;
            duration = app.DurationEditField.Value;
            t = linspace(0,duration,n);
            
            app.Data = [app.Data Amplitude*cos(2*pi*Freq*t+Phase)];
            app.Frequency = app.SamplingFrequencyEditField.Value;
        end

        % Button pushed function: ConcatanateButton_3
        function ConcatanateButton_3Pushed(app, event)
            Amp = app.AmplitudeEditField_2.Value;
            Freq = app.FrequencyEditField_2.Value;
            Pha = app.PhaseEditField_2.Value;
            t0 = app.StartingTimeEditField.Value;
            samp_freq = app.SamplingFrequencyEditField.Value;
            delta = app.LengthEditField.Value;
            
            n = app.SamplingFrequencyEditField.Value*app.DurationEditField.Value;
            duration = app.DurationEditField.Value;
            t = linspace(0,duration,n);
            s = Amp*cos(2*pi*Freq*t+Pha);
            
            if app.Windowfunctiontype2  == "Rectangular"
               window = zeros(1,n);
               window((t0*samp_freq):(t0*samp_freq)+(delta*samp_freq)) = 1;
               app.Data = [app.Data window.*s]; 

            elseif app.Windowfunctiontype2  == "Hanning"
               window = zeros(1,n);
               window((t0*samp_freq):(t0*samp_freq)+(delta*samp_freq)) = hann(delta*samp_freq+1)';
               app.Data = [app.Data window.*s]; 
            elseif app.Windowfunctiontype2  == "Hamming"
               window = zeros(1,n);
               window((t0*samp_freq):(t0*samp_freq)+(delta*samp_freq)) = hamming(delta*samp_freq+1)';
               app.Data = [app.Data window.*s]; 
            elseif app.Windowfunctiontype2  == "Blackman"
               window = zeros(1,n);
               window((t0*samp_freq):(t0*samp_freq)+(delta*samp_freq)) = blackman(delta*samp_freq+1)';
               app.Data = [app.Data window.*s]; 
            end
             app.Frequency = app.SamplingFrequencyEditField.Value;
        end

        % Button pushed function: ConcatanateButton_2
        function ConcatanateButton_2Pushed(app, event)
            Amp = str2num(app.AmplitudeofComponentsEditField.Value);
            Pha = str2num(app.PhaseofComponentsEditField.Value);
            Freq = str2num(app.FrequencyofComponentsEditField.Value);
            
            N = app.NumberofComponentsEditField.Value;
            
            n = app.SamplingFrequencyEditField.Value*app.DurationEditField.Value;
            duration = app.DurationEditField.Value;
            t = linspace(0,duration,n);
            
            summ = zeros(1,n);
            sines = zeros(N,n);
            for ind = 1:N
                sines(ind,:) = Amp(ind)*cos(2*pi*Freq(ind)*t+Pha(ind));
            end
            summ = sum(sines,1);
            
            app.Data = [app.Data summ];
            app.Frequency = app.SamplingFrequencyEditField.Value; 
         
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Data')
            xlabel(app.UIAxes, 'Time(s)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Spectrogram')
            xlabel(app.UIAxes_2, 'Time(s)')
            ylabel(app.UIAxes_2, 'Frequency(Hz)')
            zlabel(app.UIAxes_2, 'Z')
            app.DataSelectionLabel = uilabel(app.UIFigure);
            app.DataSelectionLabel.Text = 'Data Selection';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.AcquisitionTab = uitab(app.TabGroup2);
            app.AcquisitionTab.Title = 'Acquisition';
            app.LoadDataButton = uibutton(app.AcquisitionTab, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.ORLabel = uilabel(app.AcquisitionTab);
            app.ORLabel.Text = 'OR';
            app.StartRecordingButton = uibutton(app.AcquisitionTab, 'push');
            app.StartRecordingButton.ButtonPushedFcn = createCallbackFcn(app, @StartRecordingButtonPushed, true);
            app.StartRecordingButton.Text = 'Start Recording';
            app.RecordDurationEditFieldLabel = uilabel(app.AcquisitionTab);
            app.RecordDurationEditFieldLabel.Text = 'Record Duration';
            app.RecordDurationEditField = uieditfield(app.AcquisitionTab, 'text');
            app.RecordDurationEditField.ValueChangedFcn = createCallbackFcn(app, @RecordDurationEditFieldValueChanged, true);
            app.RecordDurationEditField.Value = '2';
            app.RecordFrequencyEditFieldLabel = uilabel(app.AcquisitionTab);
            app.RecordFrequencyEditFieldLabel.Text = 'Record Frequency';
            app.RecordFrequencyEditField = uieditfield(app.AcquisitionTab, 'text');
            app.RecordFrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @RecordFrequencyEditFieldValueChanged, true);
            app.RecordFrequencyEditField.Value = '40000';
            app.sLabel = uilabel(app.AcquisitionTab);
            app.sLabel.Text = 's';
            app.HzLabel = uilabel(app.AcquisitionTab);
            app.HzLabel.Text = 'Hz';
            app.LiveLampLabel = uilabel(app.AcquisitionTab);
            app.LiveLampLabel.Text = 'Live';
            app.LiveLamp = uilamp(app.AcquisitionTab);
            app.GenerationTab = uitab(app.TabGroup2);
            app.GenerationTab.Title = 'Generation';
            app.DataTypeDropDownLabel = uilabel(app.GenerationTab);
            app.DataTypeDropDownLabel.Text = 'Data Type';
            app.DataTypeDropDown = uidropdown(app.GenerationTab);
            app.DataTypeDropDown.Items = {'Sinusoidal Signal', 'Windowed Sinusoidal', 'Multiple Components'};
            app.DataTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @DataTypeDropDownValueChanged, true);
            app.DataTypeDropDown.Value = 'Sinusoidal Signal';
            app.MultipleComponentsPanel = uipanel(app.GenerationTab);
            app.MultipleComponentsPanel.Title = 'Multiple Components';
            app.NumberofComponentsEditFieldLabel = uilabel(app.MultipleComponentsPanel);
            app.NumberofComponentsEditFieldLabel.Text = 'Number of Components';
            app.NumberofComponentsEditField = uieditfield(app.MultipleComponentsPanel, 'numeric');
            app.NumberofComponentsEditField.Value = 3;
            app.GenerateButton_3 = uibutton(app.MultipleComponentsPanel, 'push');
            app.GenerateButton_3.ButtonPushedFcn = createCallbackFcn(app, @GenerateButton_3Pushed, true);
            app.GenerateButton_3.Text = 'Generate';
            app.AmplitudeofComponentsEditFieldLabel = uilabel(app.MultipleComponentsPanel);
            app.AmplitudeofComponentsEditFieldLabel.Text = 'Amplitude of Components';
            app.AmplitudeofComponentsEditField = uieditfield(app.MultipleComponentsPanel, 'text');
            app.AmplitudeofComponentsEditField.Value = '10,20,30';
            app.PhaseofComponentsEditFieldLabel = uilabel(app.MultipleComponentsPanel);
            app.PhaseofComponentsEditFieldLabel.Text = 'Phase of Components';
            app.PhaseofComponentsEditField = uieditfield(app.MultipleComponentsPanel, 'text');
            app.PhaseofComponentsEditField.Value = '0,30,60';
            app.PleaseseperatethecomponentswithcommaLabel = uilabel(app.MultipleComponentsPanel);
            app.PleaseseperatethecomponentswithcommaLabel.Text = 'Please seperate the components with comma';
            app.FrequencyofComponentsEditFieldLabel = uilabel(app.MultipleComponentsPanel);
            app.FrequencyofComponentsEditFieldLabel.Text = 'Frequency of Components';
            app.FrequencyofComponentsEditField = uieditfield(app.MultipleComponentsPanel, 'text');
            app.FrequencyofComponentsEditField.Value = '100,300,500';
            app.ConcatanateButton_2 = uibutton(app.MultipleComponentsPanel, 'push');
            app.ConcatanateButton_2.ButtonPushedFcn = createCallbackFcn(app, @ConcatanateButton_2Pushed, true);
            app.ConcatanateButton_2.Text = 'Concatanate';
            app.SamplingFrequencyEditFieldLabel = uilabel(app.GenerationTab);
            app.SamplingFrequencyEditFieldLabel.Text = 'Sampling Frequency';
            app.SamplingFrequencyEditField = uieditfield(app.GenerationTab, 'numeric');
            app.SamplingFrequencyEditField.Value = 1000;
            app.DurationEditFieldLabel = uilabel(app.GenerationTab);
            app.DurationEditFieldLabel.Text = 'Duration';
            app.DurationEditField = uieditfield(app.GenerationTab, 'numeric');
            app.DurationEditField.Value = 10;
            app.WindowedSinusoidalPanel = uipanel(app.GenerationTab);
            app.WindowedSinusoidalPanel.Title = 'Windowed Sinusoidal';
            app.WindowTypeDropDownLabel = uilabel(app.WindowedSinusoidalPanel);
            app.WindowTypeDropDownLabel.Text = 'Window Type';
            app.WindowTypeDropDown = uidropdown(app.WindowedSinusoidalPanel);
            app.WindowTypeDropDown.Items = {'Rectangular', 'Hanning', 'Hamming', 'Blackman'};
            app.WindowTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @WindowTypeDropDownValueChanged, true);
            app.WindowTypeDropDown.Value = 'Rectangular';
            app.AmplitudeEditField_2Label = uilabel(app.WindowedSinusoidalPanel);
            app.AmplitudeEditField_2Label.Text = 'Amplitude';
            app.AmplitudeEditField_2 = uieditfield(app.WindowedSinusoidalPanel, 'numeric');
            app.AmplitudeEditField_2.Value = 10;
            app.FrequencyEditField_2Label = uilabel(app.WindowedSinusoidalPanel);
            app.FrequencyEditField_2Label.Text = 'Frequency';
            app.FrequencyEditField_2 = uieditfield(app.WindowedSinusoidalPanel, 'numeric');
            app.FrequencyEditField_2.Value = 100;
            app.PhaseEditField_2Label = uilabel(app.WindowedSinusoidalPanel);
            app.PhaseEditField_2Label.Text = 'Phase';
            app.PhaseEditField_2 = uieditfield(app.WindowedSinusoidalPanel, 'numeric');
            app.GenerateButton_2 = uibutton(app.WindowedSinusoidalPanel, 'push');
            app.GenerateButton_2.ButtonPushedFcn = createCallbackFcn(app, @GenerateButton_2Pushed, true);
            app.GenerateButton_2.Text = 'Generate';
            app.StartingTimeEditFieldLabel = uilabel(app.WindowedSinusoidalPanel);
            app.StartingTimeEditFieldLabel.Text = 'Starting Time';
            app.StartingTimeEditField = uieditfield(app.WindowedSinusoidalPanel, 'numeric');
            app.StartingTimeEditField.Value = 2;
            app.LengthEditFieldLabel = uilabel(app.WindowedSinusoidalPanel);
            app.LengthEditFieldLabel.Text = 'Length';
            app.LengthEditField = uieditfield(app.WindowedSinusoidalPanel, 'numeric');
            app.LengthEditField.Value = 3;
            app.ConcatanateButton_3 = uibutton(app.WindowedSinusoidalPanel, 'push');
            app.ConcatanateButton_3.ButtonPushedFcn = createCallbackFcn(app, @ConcatanateButton_3Pushed, true);
            app.ConcatanateButton_3.Text = 'Concatanate';
            app.SinusoidalSignalPanel = uipanel(app.GenerationTab);
            app.SinusoidalSignalPanel.Title = 'Sinusoidal Signal';
            app.AmplitudeEditFieldLabel = uilabel(app.SinusoidalSignalPanel);
            app.AmplitudeEditFieldLabel.Text = 'Amplitude';
            app.AmplitudeEditField = uieditfield(app.SinusoidalSignalPanel, 'numeric');
            app.AmplitudeEditField.Value = 10;
            app.FrequencyEditFieldLabel = uilabel(app.SinusoidalSignalPanel);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.SinusoidalSignalPanel, 'numeric');
            app.FrequencyEditField.Value = 100;
            app.PhaseEditFieldLabel = uilabel(app.SinusoidalSignalPanel);
            app.PhaseEditFieldLabel.Text = 'Phase';
            app.PhaseEditField = uieditfield(app.SinusoidalSignalPanel, 'numeric');
            app.GenerateButton = uibutton(app.SinusoidalSignalPanel, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.ConcatanateButton = uibutton(app.SinusoidalSignalPanel, 'push');
            app.ConcatanateButton.ButtonPushedFcn = createCallbackFcn(app, @ConcatanateButtonPushed, true);
            app.ConcatanateButton.Text = 'Concatanate';
            app.SpectrogramTab = uitab(app.TabGroup2);
            app.SpectrogramTab.Title = 'Spectrogram';
            app.WindowLengthEditFieldLabel = uilabel(app.SpectrogramTab);
            app.WindowLengthEditFieldLabel.Text = 'Window Length';
            app.WindowLengthEditField = uieditfield(app.SpectrogramTab, 'numeric');
            app.WindowLengthEditField.Value = 1001;
            app.WindowShiftEditFieldLabel = uilabel(app.SpectrogramTab);
            app.WindowShiftEditFieldLabel.Text = 'Window Shift';
            app.WindowShiftEditField = uieditfield(app.SpectrogramTab, 'numeric');
            app.WindowShiftEditField.Value = 1;
            app.WindowFunctionDropDownLabel = uilabel(app.SpectrogramTab);
            app.WindowFunctionDropDownLabel.Text = 'Window Function';
            app.WindowFunctionDropDown = uidropdown(app.SpectrogramTab);
            app.WindowFunctionDropDown.Items = {'Rectangular', 'Hanning', 'Hamming', 'Blackman'};
            app.WindowFunctionDropDown.ValueChangedFcn = createCallbackFcn(app, @WindowFunctionDropDownValueChanged, true);
            app.WindowFunctionDropDown.Value = 'Rectangular';
            app.PLOTDATAButton = uibutton(app.UIFigure, 'push');
            app.PLOTDATAButton.ButtonPushedFcn = createCallbackFcn(app, @PLOTDATAButtonPushed, true);
            app.PLOTDATAButton.Text = 'PLOT DATA';
            app.PLOTSTFTButton = uibutton(app.UIFigure, 'push');
            app.PLOTSTFTButton.ButtonPushedFcn = createCallbackFcn(app, @PLOTSTFTButtonPushed, true);
            app.PLOTSTFTButton.Text = 'PLOT STFT';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = dsp_part1_new

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end