classdef part2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ReceiverPanel                matlab.ui.container.Panel
        DecodeButton                 matlab.ui.control.Button
        msLabel_4                    matlab.ui.control.Label
        msLabel_3                    matlab.ui.control.Label
        DecoderTypeSwitch            matlab.ui.control.Switch
        DecoderTypeSwitchLabel       matlab.ui.control.Label
        SpectrogramButton            matlab.ui.control.Button
        TimeDomainButton             matlab.ui.control.Button
        DecodedSignalLabel           matlab.ui.control.Label
        TextArea_2                   matlab.ui.control.TextArea
        ListeningLabel               matlab.ui.control.Label
        StopButton                   matlab.ui.control.Button
        StartButton                  matlab.ui.control.Button
        TrEditField_2                matlab.ui.control.NumericEditField
        TrEditField_2Label           matlab.ui.control.Label
        TdEditField_2                matlab.ui.control.NumericEditField
        TdEditField_2Label           matlab.ui.control.Label
        Panel_2                      matlab.ui.container.Panel
        Panel_3                      matlab.ui.container.Panel
        UIAxes2                      matlab.ui.control.UIAxes
        TransmitterPanel             matlab.ui.container.Panel
        msLabel_2                    matlab.ui.control.Label
        msLabel                      matlab.ui.control.Label
        WindowFunctionDropDown       matlab.ui.control.DropDown
        WindowFunctionDropDownLabel  matlab.ui.control.Label
        SaveButton                   matlab.ui.control.Button
        PlayButton                   matlab.ui.control.Button
        SpectrogramDisplayLabel      matlab.ui.control.Label
        TimedisplayLabel             matlab.ui.control.Label
        WindowShiftEditField         matlab.ui.control.NumericEditField
        WindowShiftEditFieldLabel    matlab.ui.control.Label
        WindowLengthEditField        matlab.ui.control.NumericEditField
        WindowLengthEditFieldLabel   matlab.ui.control.Label
        DButton                      matlab.ui.control.Button
        CButton                      matlab.ui.control.Button
        BButton                      matlab.ui.control.Button
        AButton                      matlab.ui.control.Button
        SpectrogramButton_2          matlab.ui.control.Button
        TimeDomainButton_2           matlab.ui.control.Button
        TrEditField                  matlab.ui.control.NumericEditField
        TrEditFieldLabel             matlab.ui.control.Label
        TdEditField                  matlab.ui.control.NumericEditField
        TdEditFieldLabel             matlab.ui.control.Label
        AmplitudeEditField           matlab.ui.control.NumericEditField
        AmplitudeEditFieldLabel      matlab.ui.control.Label
        Button_sharp                 matlab.ui.control.Button
        Button_0                     matlab.ui.control.Button
        Button_star                  matlab.ui.control.Button
        Button_9                     matlab.ui.control.Button
        Button_8                     matlab.ui.control.Button
        Button_7                     matlab.ui.control.Button
        Button_6                     matlab.ui.control.Button
        Button_5                     matlab.ui.control.Button
        Button_4                     matlab.ui.control.Button
        Button_3                     matlab.ui.control.Button
        Button_2                     matlab.ui.control.Button
        Button_1                     matlab.ui.control.Button
        Panel                        matlab.ui.container.Panel
        TextArea                     matlab.ui.control.TextArea
        ClearButton                  matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        
        Signal % signal that will be sent
        sequence
        recorded_data;
        
        low_freqs = [697, 770, 852, 941];
        high_freqs = [1209, 1336, 1477, 1633];
        Fs = 44100;
        
        recObj = audiorecorder(44100,16,1);
    end
    
    methods (Access = private)
        
        function signal = calculate_dtmf(app)
            
            app.sequence = app.TextArea.Value;
            seq = cell2mat(app.sequence);
            
            Td = app.TdEditField.Value;
            Tr = app.TrEditField.Value;
            A = app.AmplitudeEditField.Value;
                            
            nr = app.Fs*Tr*1e-3;
            nd = linspace(0,Td*1e-3,app.Fs*Td*1e-3);
            resting = zeros(1,nr);
            
            for ind = 1:length(seq)
                
                switch seq(ind)
                    case "1"
                        f1 = app.high_freqs(1);
                        f2 = app.low_freqs(1);
                    case "2"
                        f1 = app.high_freqs(2);
                        f2 = app.low_freqs(1);
                    case "3"
                        f1 = app.high_freqs(3);
                        f2 = app.low_freqs(1);
                    case "4"
                        f1 = app.high_freqs(1);
                        f2 = app.low_freqs(2);
                    case "5"
                        f1 = app.high_freqs(2);
                        f2 = app.low_freqs(2);
                    case "6"
                        f1 = app.high_freqs(3);
                        f2 = app.low_freqs(2);
                    case "7"
                        f1 = app.high_freqs(1);
                        f2 = app.low_freqs(3);
                    case "8"
                        f1 = app.high_freqs(2);
                        f2 = app.low_freqs(3);
                    case "9"
                        f1 = app.high_freqs(3);
                        f2 = app.low_freqs(3);
                    case "*"
                        f1 = app.high_freqs(1);
                        f2 = app.low_freqs(4);
                    case "0"
                        f1 = app.high_freqs(2);
                        f2 = app.low_freqs(4);
                    case "#"
                        f1 = app.high_freqs(3);
                        f2 = app.low_freqs(4);
                    case "A"
                        f1 = app.high_freqs(4);
                        f2 = app.low_freqs(1);
                    case "B"
                        f1 = app.high_freqs(4);
                        f2 = app.low_freqs(2);
                    case "C"
                        f1 = app.high_freqs(4);
                        f2 = app.low_freqs(3);
                    case "D"
                        f1 = app.high_freqs(4);
                        f2 = app.low_freqs(4);
                        
                end

                durationn = A*sin(2*pi*f1*nd)+A*sin(2*pi*f2*nd);
                
                
                mixed = cat(2,resting,durationn);
                app.Signal = cat(2,app.Signal,mixed);
                
            end
            
            
        end


        function [total_stft,M] = STFT(app,window_sequence,window_shift)
            % the function takes the sequence, the window sequence, and the window
            % shift as input.
            
            signal = app.Signal;
            % working with row vectors.
            disp(1);
            [a,b] = size(signal);
            if b<a
                signal = signal';
            end
            
            % the length of the window sequence is 2*M+1, so we can use this to
            % calculate M.
            M = (length(window_sequence)-1)/2;
            window_length = 2*M+1;
            
            % values at which we will calculate the STFT
            n0_values = M+1:window_shift:length(signal)-M;
            % should start from M+1 since we need M previous values while calculating 
            % the STFT. (+1 because of matlab indexing)
            
            % each column are STFT results at a certain n0.
            total_stft = zeros(window_length,length(n0_values));
            for i=1:length(n0_values)
                % get the part of the sequence we will use to calculate STFT.
                current_sequence = signal(n0_values(i)-M:n0_values(i)+M);
                % apply windowing operation.
                windowed_sequence = current_sequence .*window_sequence;
            
                % calculate the magnitude of the fft in dB scale.
%                 total_stft(:,i) = 20*log10(abs(fft(windowed_sequence)));
                fft_result = fft(windowed_sequence);
                power_spectral_density = abs(fft_result).^2;
                freq_resolution = app.Frequency / (2*M+1);
                power_spectral_density = power_spectral_density / freq_resolution;
        
                total_stft(:,i) = 10*log10(power_spectral_density);
            end
        end
        
        
    function [decodedSequence] = goertzelDTMFDecoder(app, frameLength, frameOverlap)
    signal = app.Signal;
    sampleRate = app.Fs;
    % Define DTMF Frequencies and their corresponding keys
    dtmfFrequencies = [697, 770, 852, 941, 1209, 1336, 1477];
    dtmfKeys = ['1', '2', '3', 'A';
                '4', '5', '6', 'B';
                '7', '8', '9', 'C';
                '*', '0', '#', 'D'];

    % Define indices for low and high frequencies
    lowFreqIndices = 1:4;
    highFreqIndices = 5:7;

    % Frame analysis parameters
    N = length(signal);
    step = frameLength - frameOverlap;
    numFrames = floor((N - frameOverlap) / step);

    % Initialize variables
    decodedSequence = '';
    threshold = 0.1; % Threshold for detecting tone presence

    % Analyze each frame
    for frame = 1:numFrames
        % Extract frame
        frameStart = (frame - 1) * step + 1;
        frameEnd = frameStart + frameLength - 1;
        frameSignal = signal(frameStart:frameEnd);

        % Initialize tone detection
        tonePresence = zeros(1, length(dtmfFrequencies));

        % Apply Goertzel algorithm to each DTMF frequency
        for k = 1:length(dtmfFrequencies)
            % Calculate frequency bin
            omega = (2 * pi * dtmfFrequencies(k)) / sampleRate;
            coeff = 2 * cos(omega);

            % Goertzel filter initialization
            Q0 = 0; Q1 = 0; Q2 = 0;

            % Apply filter
            for n = 1:frameLength
                Q0 = coeff * Q1 - Q2 + frameSignal(n);
                Q2 = Q1;
                Q1 = Q0;
            end

            % Calculate magnitude squared
            magnitudeSquared = Q1^2 + Q2^2 - Q1 * Q2 * coeff;

            % Check if frequency is present
            if magnitudeSquared > threshold
                tonePresence(k) = 1;
            end
        end

        % Decode frame to DTMF key
        lowFreqDetected = find(tonePresence(lowFreqIndices), 1);
        highFreqDetected = find(tonePresence(highFreqIndices), 1);

        if ~isempty(lowFreqDetected) && ~isempty(highFreqDetected)
            decodedDigit = dtmfKeys(lowFreqDetected, highFreqDetected - 4); % Offset by 4 for high frequencies
            decodedSequence = [decodedSequence, decodedDigit];
        end
    end
end


    end
    
    methods (Access = public)
        
        function results = decoder1(app)
            results = "111";
        end
        
        function results = decoder2(app)
            results = "123";
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_1
        function Button_1Pushed(app, event)
            app.TextArea.Value = app.TextArea.Value + "1";
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.TextArea.Value = app.TextArea.Value + "2";
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            app.TextArea.Value = app.TextArea.Value + "3";
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            app.TextArea.Value = app.TextArea.Value + "4";
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            app.TextArea.Value = app.TextArea.Value + "5";
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            app.TextArea.Value = app.TextArea.Value + "6";
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            app.TextArea.Value = app.TextArea.Value + "7";
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            app.TextArea.Value = app.TextArea.Value + "8";
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            app.TextArea.Value = app.TextArea.Value + "9";
        end

        % Button pushed function: Button_star
        function Button_starPushed(app, event)
            app.TextArea.Value = app.TextArea.Value + "*";
        end

        % Button pushed function: Button_0
        function Button_0Pushed(app, event)
            app.TextArea.Value = app.TextArea.Value + "0";
        end

        % Button pushed function: Button_sharp
        function Button_sharpPushed(app, event)
            app.TextArea.Value = app.TextArea.Value + "#";
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.TextArea.Value = "";
            app.Signal = 0;
        end

        % Button pushed function: AButton
        function AButtonPushed(app, event)
            app.TextArea.Value = app.TextArea.Value + "A";
        end

        % Button pushed function: BButton
        function BButtonPushed(app, event)
            app.TextArea.Value = app.TextArea.Value + "B";
        end

        % Button pushed function: CButton
        function CButtonPushed(app, event)
            app.TextArea.Value = app.TextArea.Value + "C";
        end

        % Button pushed function: DButton
        function DButtonPushed(app, event)
            app.TextArea.Value = app.TextArea.Value + "D";
        end

        % Button pushed function: TimeDomainButton_2
        function TimeDomainButton_2Pushed(app, event)
            app.Signal = 0;
            calculate_dtmf(app);
            n = length(cell2mat(app.sequence));
            xaxis = linspace(1,n*(app.TdEditField.Value+app.TrEditField.Value),length(app.Signal));
            title(app.UIAxes,"Time Plot");
            xlabel(app.UIAxes,"ms");
            ylabel(app.UIAxes,"Signal");
            plot(app.UIAxes,xaxis,app.Signal);
        end

        % Button pushed function: SpectrogramButton_2
        function SpectrogramButton_2Pushed(app, event)
            calculate_dtmf(app);
            
            [S,F,T] = spectrogram(app.Signal);
            imagesc(app.UIAxes,T,F,log(1+abs(S)));    
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            sound(app.Signal,app.Fs);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            [FileName,PathName] = uiputfile(['dtmf_decoder.','mat'],'Save data');
            data = app.Signal;
            save([PathName,FileName],"data",'-mat');
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
               record(app.recObj);
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            stop(app.recObj);
            app.recorded_data = getaudiodata(app.recObj);  
        end

        % Button pushed function: DecodeButton
        function DecodeButtonPushed(app, event)
            if(app.DecoderTypeSwitch.Value == "Spectrogram Based Method")
                %app.TextArea_2.Value = app.();
                %buradaki decoderlar bizim decoderlarla değiştirilecek
                %switch denemek için yazdım
                app.TextArea_2.Value = app.decoder2();
                
                
            else
                a = app.goertzelDTMFDecoder(200,100);
            end
            
            
        end

        % Button pushed function: TimeDomainButton
        function TimeDomainButtonPushed(app, event)
            
            %xaxis = linspace(1,n*(app.TdEditField.Value+app.TrEditField.Value),length(app.Signal));
            title(app.UIAxes2,"Time Plot");
            xlabel(app.UIAxes2,"ms");
            ylabel(app.UIAxes2,"Signal");
            
            plot(app.UIAxes2,app.recorded_data);
        end

        % Button pushed function: SpectrogramButton
        function SpectrogramButtonPushed(app, event)
            [S,F,T] = spectrogram(app.recorded_data);
            imagesc(app.UIAxes2,T,F,log(1+abs(S)));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TransmitterPanel = uipanel(app.UIFigure);
            app.TransmitterPanel.Title = 'Transmitter Panel';
            app.UIAxes = uiaxes(app.TransmitterPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Panel = uipanel(app.TransmitterPanel);
            app.ClearButton = uibutton(app.Panel, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.TextArea = uitextarea(app.Panel);
            app.Button_1 = uibutton(app.TransmitterPanel, 'push');
            app.Button_1.ButtonPushedFcn = createCallbackFcn(app, @Button_1Pushed, true);
            app.Button_1.Text = '1';
            app.Button_2 = uibutton(app.TransmitterPanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '2';
            app.Button_3 = uibutton(app.TransmitterPanel, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '3';
            app.Button_4 = uibutton(app.TransmitterPanel, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '4';
            app.Button_5 = uibutton(app.TransmitterPanel, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '5';
            app.Button_6 = uibutton(app.TransmitterPanel, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '7';
            app.Button_7 = uibutton(app.TransmitterPanel, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '6';
            app.Button_8 = uibutton(app.TransmitterPanel, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '9';
            app.Button_9 = uibutton(app.TransmitterPanel, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '8';
            app.Button_star = uibutton(app.TransmitterPanel, 'push');
            app.Button_star.ButtonPushedFcn = createCallbackFcn(app, @Button_starPushed, true);
            app.Button_star.Text = '*';
            app.Button_0 = uibutton(app.TransmitterPanel, 'push');
            app.Button_0.ButtonPushedFcn = createCallbackFcn(app, @Button_0Pushed, true);
            app.Button_0.Text = '0';
            app.Button_sharp = uibutton(app.TransmitterPanel, 'push');
            app.Button_sharp.ButtonPushedFcn = createCallbackFcn(app, @Button_sharpPushed, true);
            app.Button_sharp.Text = '#';
            app.AmplitudeEditFieldLabel = uilabel(app.TransmitterPanel);
            app.AmplitudeEditFieldLabel.Text = 'Amplitude';
            app.AmplitudeEditField = uieditfield(app.TransmitterPanel, 'numeric');
            app.TdEditFieldLabel = uilabel(app.TransmitterPanel);
            app.TdEditFieldLabel.Text = 'Td';
            app.TdEditField = uieditfield(app.TransmitterPanel, 'numeric');
            app.TrEditFieldLabel = uilabel(app.TransmitterPanel);
            app.TrEditFieldLabel.Text = 'Tr';
            app.TrEditField = uieditfield(app.TransmitterPanel, 'numeric');
            app.TimeDomainButton_2 = uibutton(app.TransmitterPanel, 'push');
            app.TimeDomainButton_2.ButtonPushedFcn = createCallbackFcn(app, @TimeDomainButton_2Pushed, true);
            app.TimeDomainButton_2.Text = 'Time Domain';
            app.SpectrogramButton_2 = uibutton(app.TransmitterPanel, 'push');
            app.SpectrogramButton_2.ButtonPushedFcn = createCallbackFcn(app, @SpectrogramButton_2Pushed, true);
            app.SpectrogramButton_2.Text = 'Spectrogram';
            app.AButton = uibutton(app.TransmitterPanel, 'push');
            app.AButton.ButtonPushedFcn = createCallbackFcn(app, @AButtonPushed, true);
            app.AButton.Text = 'A';
            app.BButton = uibutton(app.TransmitterPanel, 'push');
            app.BButton.ButtonPushedFcn = createCallbackFcn(app, @BButtonPushed, true);
            app.BButton.Text = 'B';
            app.CButton = uibutton(app.TransmitterPanel, 'push');
            app.CButton.ButtonPushedFcn = createCallbackFcn(app, @CButtonPushed, true);
            app.CButton.Text = 'C';
            app.DButton = uibutton(app.TransmitterPanel, 'push');
            app.DButton.ButtonPushedFcn = createCallbackFcn(app, @DButtonPushed, true);
            app.DButton.Text = 'D';
            app.WindowLengthEditFieldLabel = uilabel(app.TransmitterPanel);
            app.WindowLengthEditFieldLabel.Text = 'Window Length';
            app.WindowLengthEditField = uieditfield(app.TransmitterPanel, 'numeric');
            app.WindowShiftEditFieldLabel = uilabel(app.TransmitterPanel);
            app.WindowShiftEditFieldLabel.Text = 'Window Shift';
            app.WindowShiftEditField = uieditfield(app.TransmitterPanel, 'numeric');
            app.TimedisplayLabel = uilabel(app.TransmitterPanel);
            app.TimedisplayLabel.Text = 'Time display';
            app.SpectrogramDisplayLabel = uilabel(app.TransmitterPanel);
            app.SpectrogramDisplayLabel.Text = 'Spectrogram Display';
            app.PlayButton = uibutton(app.TransmitterPanel, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.SaveButton = uibutton(app.TransmitterPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.WindowFunctionDropDownLabel = uilabel(app.TransmitterPanel);
            app.WindowFunctionDropDownLabel.Text = 'Window Function';
            app.WindowFunctionDropDown = uidropdown(app.TransmitterPanel);
            app.WindowFunctionDropDown.Items = {'Rectangular', 'Hanning', 'Hamming', 'Blackman'};
            app.WindowFunctionDropDown.Value = 'Rectangular';
            app.msLabel = uilabel(app.TransmitterPanel);
            app.msLabel.Text = 'ms';
            app.msLabel_2 = uilabel(app.TransmitterPanel);
            app.msLabel_2.Text = 'ms';
            app.ReceiverPanel = uipanel(app.UIFigure);
            app.ReceiverPanel.Title = 'Receiver Panel';
            app.UIAxes2 = uiaxes(app.ReceiverPanel);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.Panel_3 = uipanel(app.ReceiverPanel);
            app.Panel_2 = uipanel(app.ReceiverPanel);
            app.TdEditField_2Label = uilabel(app.ReceiverPanel);
            app.TdEditField_2Label.Text = 'Td';
            app.TdEditField_2 = uieditfield(app.ReceiverPanel, 'numeric');
            app.TrEditField_2Label = uilabel(app.ReceiverPanel);
            app.TrEditField_2Label.Text = 'Tr';
            app.TrEditField_2 = uieditfield(app.ReceiverPanel, 'numeric');
            app.StartButton = uibutton(app.ReceiverPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.ReceiverPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.ListeningLabel = uilabel(app.ReceiverPanel);
            app.ListeningLabel.Text = 'Listening';
            app.TextArea_2 = uitextarea(app.ReceiverPanel);
            app.DecodedSignalLabel = uilabel(app.ReceiverPanel);
            app.DecodedSignalLabel.Text = 'Decoded Signal';
            app.TimeDomainButton = uibutton(app.ReceiverPanel, 'push');
            app.TimeDomainButton.ButtonPushedFcn = createCallbackFcn(app, @TimeDomainButtonPushed, true);
            app.TimeDomainButton.Text = 'Time Domain';
            app.SpectrogramButton = uibutton(app.ReceiverPanel, 'push');
            app.SpectrogramButton.ButtonPushedFcn = createCallbackFcn(app, @SpectrogramButtonPushed, true);
            app.SpectrogramButton.Text = 'Spectrogram';
            app.DecoderTypeSwitchLabel = uilabel(app.ReceiverPanel);
            app.DecoderTypeSwitchLabel.Text = 'Decoder Type';
            app.DecoderTypeSwitch = uiswitch(app.ReceiverPanel, 'slider');
            app.DecoderTypeSwitch.Items = {'Spectrogram Based Method', 'Other Method'};
            app.DecoderTypeSwitch.Value = 'Spectrogram Based Method';
            app.msLabel_3 = uilabel(app.ReceiverPanel);
            app.msLabel_3.Text = 'ms';
            app.msLabel_4 = uilabel(app.ReceiverPanel);
            app.msLabel_4.Text = 'ms';
            app.DecodeButton = uibutton(app.ReceiverPanel, 'push');
            app.DecodeButton.ButtonPushedFcn = createCallbackFcn(app, @DecodeButtonPushed, true);
            app.DecodeButton.Text = 'Decode';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = part2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end